classdef DMCSimulado < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        DegraunosistemaPanel            matlab.ui.container.Panel
        EncontrarMatrizesDMCButton      matlab.ui.control.Button
        TempodeSimulaosEditField        matlab.ui.control.NumericEditField
        IntervalodeTempodoDegrauLabel   matlab.ui.control.Label
        TempodeAmostragemsEditField     matlab.ui.control.NumericEditField
        TempodeAmostragemsEditFieldLabel  matlab.ui.control.Label
        SimulaoPanel                    matlab.ui.container.Panel
        SalvarDadosButton               matlab.ui.control.Button
        RefernciaSPEditField            matlab.ui.control.NumericEditField
        RefernciaSPEditFieldLabel       matlab.ui.control.Label
        SimularButton                   matlab.ui.control.Button
        IteraesdaSimulaoEditField       matlab.ui.control.NumericEditField
        IteraesdaSimulaoEditFieldLabel  matlab.ui.control.Label
        ControladorPanel                matlab.ui.container.Panel
        DefinirParmterosdoControladorButton  matlab.ui.control.Button
        PesoREditField                  matlab.ui.control.NumericEditField
        PesoREditFieldLabel             matlab.ui.control.Label
        HorizontedeControleMEditField   matlab.ui.control.NumericEditField
        HorizontedeControleMEditFieldLabel  matlab.ui.control.Label
        HorizontedePredioNpEditField    matlab.ui.control.NumericEditField
        HorizontedePredioNpEditFieldLabel  matlab.ui.control.Label
        Sistema1OrdemcomatrasoPanel     matlab.ui.container.Panel
        DefinirSistemaButton            matlab.ui.control.Button
        ThetaEditField                  matlab.ui.control.NumericEditField
        ThetaEditFieldLabel             matlab.ui.control.Label
        TEditField                      matlab.ui.control.NumericEditField
        TEditFieldLabel                 matlab.ui.control.Label
        KEditField                      matlab.ui.control.NumericEditField
        KEditFieldLabel                 matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        DynamicMatrixControlDMCLabel    matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        K;
        T;
        Theta;
        
        G;      % Planta
        Gd;     % Planta (Discretizada)
        d;b0;b1;a0;   


                         % Tempo de Amostragem
        IntervaloTempoSeg;  % Tempo de Simulação
        u_step;
        y_step;
    	t;
        
        Ts;
        Np;    % H Predição
        M;     % H Controle
        
        S;     % Matriz dinâmica S
        H;

        % Valor medido (Simulado)
        yRealk;
        % Y de Predição
        yPredicao = 0;
        % Ações passadas P
        P;
        % Delta U's já aplicados (passados)
        DeltaUPassados;

        % Fator de Supressão R
        Peso;
        R;

        % Erro
        e = 0;

        % Variáveis do sistema simulado
        iteracoesDaSimulacao;
        y;
        u;
        tempo;
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: DefinirSistemaButton
        function DefinirSistemaButtonPushed(app, event)
            try
            app.K       = app.KEditField.Value;
            app.T       = app.TEditField.Value;
            app.Theta   = app.ThetaEditField.Value;
            
            app.G           = tf(app.K,[app.T 1]);
            app.G.outputd   = app.Theta;
            catch
                 msgbox("Erro ao Definir Sistema!");
            end
            msgbox("Sistema Definido!");
        end

        % Button pushed function: EncontrarMatrizesDMCButton
        function EncontrarMatrizesDMCButtonPushed(app, event)
            try
            %% Obtendo modelo discreto - simular y(k)
            % Modificar y(k) na simulação
            app.Ts  = app.TempodeAmostragemsEditField.Value;
            app.Gd  = c2d(app.G,app.Ts);
            app.b1 = app.Gd.num{1}(1);
            app.b0 = app.Gd.num{1}(2);
            app.a0 = abs(app.Gd.den{1}(2));
            app.d = app.Gd.OutputDelay + app.Gd.IODelay;


            %% Obtendo valores do Step p/ calcular parâmetros DMC
            app.IntervaloTempoSeg = app.TempodeSimulaosEditField.Value;

            app.t               = 0:app.Ts:app.IntervaloTempoSeg;
            app.u_step      = ones(1,length(app.t));
            app.y_step          = lsim(app.G,app.u_step,app.t);
          

            %% Obtendo a matriz dinâmica S (coeficientes Sn da resposta ao degrau)
            S_n         = app.y_step(2:end); 
            app.S       = toeplitz(S_n(1:app.Np),[S_n(1) zeros(1,app.M-1)]);

            %% Encontrando coeficientes da resposta ao impulto (hi)
            h = zeros(app.Np,1);
            h(1) = app.S(1);
            for j = 2:app.Np
               h(j) = app.S(j) - app.S(j-1);
            end

            %% Calculando matriz H como forma de calcular as ações passadas (P)
            h = h';
            app.H = h(1,2:end);
            for i = 2:app.Np
            app.H = [app.H; h(1,i+1:end) zeros(1,i-1)];
            end
            h = h';
            catch
                msgbox("Erro obtendo matrizes DMC!!");
            end
            msgbox("Matrizes Calculadas!");
        end

        % Button pushed function: DefinirParmterosdoControladorButton
        function DefinirParmterosdoControladorButtonPushed(app, event)

            %% Definir Horizontes de predição e controle (Np e M)
            try
            app.Np      = app.HorizontedePredioNpEditField.Value;      %H Predição
            app.M       = app.HorizontedeControleMEditField.Value;      %H Controle
            app.Peso    = app.PesoREditField.Value;
            catch
                msgbox("Erro ao definir parâmetros do controlador!!");
            end
                msgbox("Parâmetros Definidos");
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
            f = msgbox("Aguarde...");
            %% Iniciando variáveis para a Simulação

            % Setpoint
            ySetPoint = app.RefernciaSPEditField.Value*ones(app.Np,1);%(G11.Num{1}(2))*ones(Np,1);
            % Valor medido (Simulado)
            app.yRealk = zeros(app.Np,1);
            % Y de Predição
            app.yPredicao = 0;
            % Ações passadas P
            app.P = zeros(app.Np,1);
            % Delta U's já aplicados (passados)
            app.DeltaUPassados = zeros(app.Np-1,1);

            % Fator de Supressão R
            app.R = app.Peso*diag(diag(ones(app.M)));
            
            % Erro
            app.e = 0;
            
            % Variáveis do sistema simulado
            app.iteracoesDaSimulacao = app.IteraesdaSimulaoEditField.Value;
            app.y       = zeros(app.iteracoesDaSimulacao,1);
            app.u       = zeros(app.iteracoesDaSimulacao,1);
            app.tempo   = zeros(app.iteracoesDaSimulacao,1);


            %% Iniciando a Simulação
        try
           
            for k= 1:(app.iteracoesDaSimulacao)
               
                app.tempo(k) = (k-1)*app.Ts;
            % Ler Y /Simulado  
               
            if app.d == 1 
                if  k==1
                    app.y(k) = 0;
                elseif k<=2
                    app.y(k) = app.a0*app.y(k-1) + app.b1*app.u(k-1);
                else
                    app.y(k) = app.a0*app.y(k-1) + app.b1*app.u(k-1)+ app.b0*app.u(k-2);    
                end
            else        
                if  k==1
                    app.y(k) = 0;
                elseif k<=(app.d)
                    app.y(k) = app.a0*app.y(k-1);
                elseif k<=(app.d+1)
                    app.y(k) = app.a0*app.y(k-1)+app.b1*app.u(k-app.d);
                else
                    app.y(k) = app.a0*app.y(k-1)+app.b1*app.u(k-app.d)+app.b0*app.u(k-(app.d-1));    
                end
            end

                % Execute programa DMC
                app.yRealk = app.y(k)*ones(app.Np,1);

                app.e = ySetPoint - app.yRealk - app.P;
            
                %Cálculo das ações de controle
                DeltaU = (app.S'*app.S+app.R)\(app.S'*app.e); 
    
                app.DeltaUPassados = [DeltaU(1); app.DeltaUPassados(1:end-1)];

                %Predição
                app.yPredicao = app.S*DeltaU + app.yRealk + app.P; 
            
                %Cálculo das ações passadas
                p = app.H*app.DeltaUPassados;

                for i=1:app.Np
                 app.P(i) = 0;
                    for m=1:i
                     app.P(i) = app.P(i) + p(m);
                    end
                end
    
                % Aplicando sinal de controle 
                %  Escreva U
                if k == 1
                    app.u(k) = 0 + DeltaU(1);
                else
                    app.u(k) = app.u(k-1) + DeltaU(1);
                end   
            end
        catch
            msgbox("Erro na Simulação!!");
            delete(f);
        end
            delete(f);
            app.UIAxes.XLabel.String = 'Tempo (s)';
            app.UIAxes.YLabel.String = 'Magnitude';
            cla(app.UIAxes);
            hold(app.UIAxes,'on'); 
            plot(app.UIAxes,app.tempo,app.y);
            plot(app.UIAxes,app.tempo,app.u);
            plot(app.UIAxes,app.t,app.y_step)
            legend(app.UIAxes,["y","u","y step"]);
            ylim(app.UIAxes,[20 60]);
           
            axis(app.UIAxes,"tight");
        end

        % Button pushed function: SalvarDadosButton
        function SalvarDadosButtonPushed(app, event)
            filename = 'DMCDadosSimulacao.mat';

            TempoAmostragem = app.Ts;
            HPred =     app.Np;    % H Predição
            HControle = app.M;     % H Controle
            MatrizDinamica = app.S;     % Matriz dinâmica S
            MatrizR = app.R;

            TempoSimulacao = app.tempo;
            Y = app.y;
            U = app.u;
            Y_Step = app.y_step;
            TempoStep = app.t;
            save(filename,'TempoAmostragem','HPred','HControle','MatrizDinamica','MatrizR','TempoSimulacao','Y','U','TempoStep','Y_Step');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {856, 856};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {370, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DynamicMatrixControlDMCLabel = uilabel(app.LeftPanel);
            app.DynamicMatrixControlDMCLabel.Text = 'Dynamic Matrix Control (DMC)';
            app.Sistema1OrdemcomatrasoPanel = uipanel(app.LeftPanel);
            app.Sistema1OrdemcomatrasoPanel.Title = 'Sistema - 1º Ordem com atraso';
            app.Image = uiimage(app.Sistema1OrdemcomatrasoPanel);
            app.Image.ImageSource = 'Sistema.png';
            app.KEditFieldLabel = uilabel(app.Sistema1OrdemcomatrasoPanel);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.Sistema1OrdemcomatrasoPanel, 'numeric');
            app.KEditField.Value = 0.364;
            app.TEditFieldLabel = uilabel(app.Sistema1OrdemcomatrasoPanel);
            app.TEditFieldLabel.Text = 'T';
            app.TEditField = uieditfield(app.Sistema1OrdemcomatrasoPanel, 'numeric');
            app.TEditField.Value = 190.2;
            app.ThetaEditFieldLabel = uilabel(app.Sistema1OrdemcomatrasoPanel);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.ThetaEditField = uieditfield(app.Sistema1OrdemcomatrasoPanel, 'numeric');
            app.ThetaEditField.Limits = [0 Inf];
            app.ThetaEditField.Value = 9.61;
            app.DefinirSistemaButton = uibutton(app.Sistema1OrdemcomatrasoPanel, 'push');
            app.DefinirSistemaButton.ButtonPushedFcn = createCallbackFcn(app, @DefinirSistemaButtonPushed, true);
            app.DefinirSistemaButton.Text = {'Definir Sistema'; ''};
            app.ControladorPanel = uipanel(app.LeftPanel);
            app.ControladorPanel.Title = 'Controlador';
            app.HorizontedePredioNpEditFieldLabel = uilabel(app.ControladorPanel);
            app.HorizontedePredioNpEditFieldLabel.Text = 'Horizonte de Predição (Np)';
            app.HorizontedePredioNpEditField = uieditfield(app.ControladorPanel, 'numeric');
            app.HorizontedePredioNpEditField.Limits = [0 500];
            app.HorizontedePredioNpEditField.Value = 200;
            app.HorizontedeControleMEditFieldLabel = uilabel(app.ControladorPanel);
            app.HorizontedeControleMEditFieldLabel.Text = 'Horizonte de Controle (M)';
            app.HorizontedeControleMEditField = uieditfield(app.ControladorPanel, 'numeric');
            app.HorizontedeControleMEditField.Limits = [0 500];
            app.HorizontedeControleMEditField.Value = 4;
            app.PesoREditFieldLabel = uilabel(app.ControladorPanel);
            app.PesoREditFieldLabel.Text = 'Peso (R)';
            app.PesoREditField = uieditfield(app.ControladorPanel, 'numeric');
            app.PesoREditField.Value = 5;
            app.DefinirParmterosdoControladorButton = uibutton(app.ControladorPanel, 'push');
            app.DefinirParmterosdoControladorButton.ButtonPushedFcn = createCallbackFcn(app, @DefinirParmterosdoControladorButtonPushed, true);
            app.DefinirParmterosdoControladorButton.Text = 'Definir Parâmteros do Controlador';
            app.SimulaoPanel = uipanel(app.LeftPanel);
            app.SimulaoPanel.Title = 'Simulação';
            app.IteraesdaSimulaoEditFieldLabel = uilabel(app.SimulaoPanel);
            app.IteraesdaSimulaoEditFieldLabel.Text = 'Iterações da Simulação';
            app.IteraesdaSimulaoEditField = uieditfield(app.SimulaoPanel, 'numeric');
            app.IteraesdaSimulaoEditField.Limits = [0 Inf];
            app.IteraesdaSimulaoEditField.Value = 2000;
            app.SimularButton = uibutton(app.SimulaoPanel, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.RefernciaSPEditFieldLabel = uilabel(app.SimulaoPanel);
            app.RefernciaSPEditFieldLabel.Text = 'Referência (SP)';
            app.RefernciaSPEditField = uieditfield(app.SimulaoPanel, 'numeric');
            app.RefernciaSPEditField.Limits = [0 Inf];
            app.RefernciaSPEditField.Value = 0.364;
            app.SalvarDadosButton = uibutton(app.SimulaoPanel, 'push');
            app.SalvarDadosButton.ButtonPushedFcn = createCallbackFcn(app, @SalvarDadosButtonPushed, true);
            app.SalvarDadosButton.Text = {'Salvar Dados'; ''};
            app.DegraunosistemaPanel = uipanel(app.LeftPanel);
            app.DegraunosistemaPanel.Title = 'Degrau no sistema';
            app.TempodeAmostragemsEditFieldLabel = uilabel(app.DegraunosistemaPanel);
            app.TempodeAmostragemsEditFieldLabel.Text = 'Tempo de Amostragem (s)';
            app.TempodeAmostragemsEditField = uieditfield(app.DegraunosistemaPanel, 'numeric');
            app.TempodeAmostragemsEditField.Limits = [0 Inf];
            app.TempodeAmostragemsEditField.Value = 2;
            app.IntervalodeTempodoDegrauLabel = uilabel(app.DegraunosistemaPanel);
            app.IntervalodeTempodoDegrauLabel.Text = 'Tempo de Simulação (s)';
            app.TempodeSimulaosEditField = uieditfield(app.DegraunosistemaPanel, 'numeric');
            app.TempodeSimulaosEditField.Limits = [0 Inf];
            app.TempodeSimulaosEditField.Value = 2000;
            app.EncontrarMatrizesDMCButton = uibutton(app.DegraunosistemaPanel, 'push');
            app.EncontrarMatrizesDMCButton.ButtonPushedFcn = createCallbackFcn(app, @EncontrarMatrizesDMCButtonPushed, true);
            app.EncontrarMatrizesDMCButton.Text = 'Encontrar Matrizes - DMC';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'Tempo (s)')
            ylabel(app.UIAxes, 'Magnitude')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DMCSimulado

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end