classdef StepIdentificacaoOPC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        PararButton             matlab.ui.control.Button
        ConectarButton          matlab.ui.control.Button
        SalvarDadosButton       matlab.ui.control.Button
        AplicarMVButton         matlab.ui.control.Button
        ControledoSistemaPanel  matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        LabelPV2                matlab.ui.control.Label
        LabelPV1                matlab.ui.control.Label
        LabelCte1               matlab.ui.control.Label
        LabelCte2               matlab.ui.control.Label
        MVSpinner               matlab.ui.control.Spinner
        MVSpinnerLabel          matlab.ui.control.Label
        MalhaDropDown           matlab.ui.control.DropDown
        MalhaDropDownLabel      matlab.ui.control.Label
        MalhaAbertaLabel        matlab.ui.control.Label
        StepIdentificaoLabel    matlab.ui.control.Label
        RightPanel              matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        UIAxesMV                matlab.ui.control.UIAxes
        UIAxesTemp              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Timer = 0; % Description
        Conectado = 0;
        opc;
        PVS;
        PV1; % Array de dados p/ Salvar e Plot
        PV2; % Array de dados p/ Salvar e Plot
        MV1; % Array de dados p/ Salvar e Plot
        MV2; % Array de dados p/ Salvar e Plot
        MV1Valor = 0;
        MV2Valor = 0;
        iteracao = 1;
    end
    
    methods (Access = public)
        
        function TimerCallback(app, obj, event, string_arg)
            if strcmp(app.opc{1}.Status,'Connected')
                try
                    app.PVS = ReceberPVs(app.opc);
                    app.PV1 = [app.PV1 app.PVS(1)];
                    app.PV2 = [app.PV2 app.PVS(2)];
                    app.MV1 = [app.MV1 app.MV1Valor];
                    app.MV2 = [app.MV2 app.MV2Valor];
                    app.iteracao = app.iteracao + 1;
                    AtualizarPlot(app);
                catch
                    msgbox("Desconectado! Erro ao Receber PVs.");
                    stop(app.Timer);
                end
            else
                msgbox("Desconectado!");
                stop(app.Timer);
            end      
            app.LabelPV1.Text = strcat(sprintf('%.2f',app.PVS(1)),' ºC');
            app.LabelPV2.Text = strcat(sprintf('%.2f',app.PVS(2)),' ºC');
        end
        
        function AtualizarPlot(app)
            %Plot 1 - Valores de PV
            cla(app.UIAxesTemp);
            plot(app.UIAxesTemp,app.PV1);
            hold(app.UIAxesTemp,'on'); 
            plot(app.UIAxesTemp,app.PV2);
            
            legend(app.UIAxesTemp,["Malha 1","Malha 2"]);
            ylim(app.UIAxesTemp,[20 60]);
           
            axis(app.UIAxesTemp,"tight");

            
            %Plot 2 - Valores de MV

            cla(app.UIAxesMV);
            plot(app.UIAxesMV,app.MV1);
            hold(app.UIAxesMV,"on");
            plot(app.UIAxesMV,app.MV2);
            
            legend(app.UIAxesMV,["Malha 1","Malha 2"]);
 
            axis(app.UIAxesMV,"tight");
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %%app.Timer = timer('StartDelay',2,'Period',2,'ExecutionMode','fixedSpacing','TasksToExecute', Inf);
            %%app.Timer.TimerFcn = @app.TimerCallback;
            
            while(true)
                if app.Conectado == 1
                    if strcmp(app.opc{1}.Status,'Connected')
                    try
                        app.PVS = ReceberPVs(app.opc);
                        app.PV1 = [app.PV1 app.PVS(1)];
                        app.PV2 = [app.PV2 app.PVS(2)];
                        app.MV1 = [app.MV1 app.MV1Valor];
                        app.MV2 = [app.MV2 app.MV2Valor];
                        app.iteracao = app.iteracao + 1;
                        AtualizarPlot(app);
                    catch
                        msgbox("Desconectado! Erro ao Receber PVs.");
                        %stop(app.Timer);
                        app.Conectado = 0;
                    end
                    else
                        msgbox("Desconectado!");
                        %stop(app.Timer);
                        app.Conectado = 0;
                    end      
                app.LabelPV1.Text = strcat(sprintf('%.2f',app.PVS(1)),' ºC');
                app.LabelPV2.Text = strcat(sprintf('%.2f',app.PVS(2)),' ºC');
                end
                pause(2);
            end
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            %if strcmp(app.Timer.Running,'on')
            %    stop(app.Timer);
            %end
            app.Conectado = 0;
            cla(app.UIAxesTemp);
            cla(app.UIAxesMV);
            clear app.opc;
            app.PVS = 0;
            app.PV1 = 0; % Array de dados p/ Salvar e Plot
            app.PV2 = 0; % Array de dados p/ Salvar e Plot
            app.MV1 = 0; % Array de dados p/ Salvar e Plot
            app.MV2 = 0; % Array de dados p/ Salvar e Plot
            app.MV1Valor = 0;
            app.MV2Valor = 0;
            app.iteracao = 1;

            app.opc = InicializarClienteOPC('localhost',48030);
            while strcmp(app.opc{1}.Name,'Erro')
                messageBoxErro = msgbox("Não foi possível conectar ao servidor! Tentando Novamente!");
                [app.opc] = InicializarClienteOPC('localhost',48030);
                pause(1);
                delete(messageBoxErro);
            end        
            msgbox("Conectado!");
            %incializando PV1 e PV2
            try
                    zerarMVs = [0 ; 0];
                    app.PVS = ReceberPVs(app.opc);
                    app.PV1 = app.PVS(1);
                    app.PV2 = app.PVS(2);
                    DefinirMVs(app.opc,0,zerarMVs);
            catch
                    msgbox("Erro Receber PVs ou enviar MVs!");
                    stop(app.Timer);
            end
            app.Conectado = 1;
            %if strcmp(app.Timer.Running,'off')
            %    start(app.Timer);
            %end
        end

        % Button pushed function: PararButton
        function PararButtonPushed(app, event)
            app.Conectado = 0;
            %stop(app.Timer);
        end

        % Button pushed function: AplicarMVButton
        function AplicarMVButtonPushed(app, event)
            if strcmp(app.opc{1}.Status,'Connected')

                if strcmp(app.MalhaDropDown.Value ,'Malha1')
                    ValorAnterior = app.MV1Valor;
                    app.MV1Valor = app.MVSpinner.Value;
                    try
                        DefinirMVs(app.opc,1,app.MV1Valor);
                    catch
                        msgbox("Não foi possível definir MV!");
                        app.MV1Valor = ValorAnterior;
                    end

                else
                    ValorAnterior = app.MV2Valor;
                    app.MV2Valor = app.MVSpinner.Value;
                    try
                        DefinirMVs(app.opc,2,app.MV2Valor);
                    catch
                        msgbox("Não foi possível definir MV!");
                        app.MV2Valor = ValorAnterior;
                    end
                end

            else
                msgbox("Você está desconectado!");
                %stop(app.Timer);
                app.Conectado = 0;
            end
        end

        % Button pushed function: SalvarDadosButton
        function SalvarDadosButtonPushed(app, event)
            filename = 'dados.mat';
            mv1 = app.MV1;
            mv2 = app.MV2;
            pv1 = app.PV1;
            pv2 = app.PV2;
            save(filename,'mv1','mv2','pv1','pv2');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {571, 571};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {235, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StepIdentificaoLabel = uilabel(app.LeftPanel);
            app.StepIdentificaoLabel.Text = 'Step - Identificação';
            app.MalhaAbertaLabel = uilabel(app.LeftPanel);
            app.MalhaAbertaLabel.Text = 'Malha Aberta';
            app.ControledoSistemaPanel = uipanel(app.LeftPanel);
            app.ControledoSistemaPanel.Title = 'Controle do Sistema';
            app.GridLayout2 = uigridlayout(app.ControledoSistemaPanel);
            app.MalhaDropDownLabel = uilabel(app.GridLayout2);
            app.MalhaDropDownLabel.Text = 'Malha';
            app.MalhaDropDown = uidropdown(app.GridLayout2);
            app.MalhaDropDown.Items = {'Malha1', 'Malha 2'};
            app.MalhaDropDown.Value = 'Malha1';
            app.MVSpinnerLabel = uilabel(app.GridLayout2);
            app.MVSpinnerLabel.Text = 'MV';
            app.MVSpinner = uispinner(app.GridLayout2);
            app.MVSpinner.Limits = [0 100];
            app.LabelCte2 = uilabel(app.GridLayout2);
            app.LabelCte2.Text = 'PV 1';
            app.LabelCte1 = uilabel(app.GridLayout2);
            app.LabelCte1.Text = 'PV 2';
            app.LabelPV1 = uilabel(app.GridLayout2);
            app.LabelPV1.Text = '0';
            app.LabelPV2 = uilabel(app.GridLayout2);
            app.LabelPV2.Text = '0';
            app.AplicarMVButton = uibutton(app.LeftPanel, 'push');
            app.AplicarMVButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarMVButtonPushed, true);
            app.AplicarMVButton.Text = 'Aplicar MV';
            app.SalvarDadosButton = uibutton(app.LeftPanel, 'push');
            app.SalvarDadosButton.ButtonPushedFcn = createCallbackFcn(app, @SalvarDadosButtonPushed, true);
            app.SalvarDadosButton.Text = 'Salvar Dados';
            app.ConectarButton = uibutton(app.LeftPanel, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.PararButton = uibutton(app.LeftPanel, 'push');
            app.PararButton.ButtonPushedFcn = createCallbackFcn(app, @PararButtonPushed, true);
            app.PararButton.Text = 'Parar';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAxesTemp = uiaxes(app.GridLayout3);
            title(app.UIAxesTemp, 'Temperaturas')
            xlabel(app.UIAxesTemp, 'Tempo (s)')
            ylabel(app.UIAxesTemp, 'PV (ºC)')
            zlabel(app.UIAxesTemp, 'Z')
            app.UIAxesMV = uiaxes(app.GridLayout3);
            title(app.UIAxesMV, 'Variáveis Manipuladas')
            xlabel(app.UIAxesMV, 'Tempo (s)')
            ylabel(app.UIAxesMV, 'MV (%)')
            zlabel(app.UIAxesMV, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StepIdentificacaoOPC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end